classdef App_label_link < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NextButton               matlab.ui.control.Button
        LastButton               matlab.ui.control.Button
        RemoveButton             matlab.ui.control.Button
        RecoverButton            matlab.ui.control.Button
        SaveresultButton         matlab.ui.control.Button
        LinkindexEditFieldLabel  matlab.ui.control.Label
        LinkindexEditField       matlab.ui.control.EditField
        Labelinglinks            matlab.ui.control.Label
        ZoomoutButton            matlab.ui.control.Button
        ZoominButton             matlab.ui.control.Button
        LabelEditFieldLabel      matlab.ui.control.Label
        LabelEditField           matlab.ui.control.EditField
        NotsureButton            matlab.ui.control.Button
        ImplayButton             matlab.ui.control.Button
        ArtefactButton           matlab.ui.control.Button
        NotsurebutkeepButton     matlab.ui.control.Button
        ArtefactbutkeepButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        test_label_idx %integer
        num_test_link
        %         link_label_list
        link_bbox_expansion_length
        
        removal_Q % Logical array
        not_sure_Q % Logical array
        artefact_Q % Logical array
        not_sure_but_keep_Q % Logical array
        artefact_but_keep_Q % Logical array
        % Input
        vessel_graph
        vessel_image
        vessel_mask
        vessel_skl
        link_cc_ind
        
        fig_hdl
    end
    
    properties (Access = public)
        output_name
        % Output
        result
        finishQ
    end
    
    methods (Access = private)
        
        function fun_visualization(app)
            if isempty(app.link_bbox_expansion_length)
                app.link_bbox_expansion_length = 10;
            end
            if isvalid(app.fig_hdl)
                clf(app.fig_hdl, 'reset');
            else
                app.fig_hdl = figure('Name','3D Visualization');
            end
            image_size = size(app.vessel_image);
            check_link_idx = app.test_label_idx;
            link_ind = app.link_cc_ind{check_link_idx};
            link_global_sub = zeros(numel(link_ind), 3);
            [link_global_sub(:,1), link_global_sub(:,2), link_global_sub(:,3)] = ind2sub(image_size, link_ind);
            link_global_bbox = zeros(1,6);
            link_global_bbox(1:3) = max(1, min(link_global_sub, [], 1) - app.link_bbox_expansion_length);
            link_global_bbox_max = min(image_size, max(link_global_sub, [], 1) + app.link_bbox_expansion_length) ;
            link_global_bbox(4:6) = link_global_bbox_max - link_global_bbox(1:3) + 1;
            
            link_local_sub = bsxfun(@minus, link_global_sub, link_global_bbox(1:3)) + 1;
            vis_local_skl = crop_bbox3(app.vessel_skl, link_global_bbox, 'default');
            vis_skl_ind = find(vis_local_skl);
            vis_skl_ind = setdiff(vis_skl_ind, sub2ind(link_global_bbox(4:6), link_local_sub(:,1), link_local_sub(:,2), link_local_sub(:,3) ));
            vis_skl_sub = zeros(numel(vis_skl_ind), 3);
            [vis_skl_sub(:,1), vis_skl_sub(:,2), vis_skl_sub(:,3)] = ind2sub(link_global_bbox(4:6), vis_skl_ind);
            vis_local_image = crop_bbox3(app.vessel_image, link_global_bbox, 'default');
            vis_local_mask = crop_bbox3(app.vessel_mask, link_global_bbox, 'default');
            % Plot
            ax_1 = subplot(1,5,1:2);
            plot3(ax_1, link_local_sub(:,2),link_local_sub(:,1), link_local_sub(:,3),'-g', 'LineWidth', 3);
            hold(ax_1, 'on');
            scatter3(ax_1, vis_skl_sub(:,2),vis_skl_sub(:,1), vis_skl_sub(:,3), 'k', 'filled');
            tmp_fv = isosurface(vis_local_mask,false);
            tmp_fv = reducepatch(tmp_fv);
            patch(ax_1, tmp_fv, 'FaceColor', 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.25);
            ax_1.XLabel.String = 'X (\mum)';
            ax_1.YLabel.String = 'Y (\mum)';
            ax_1.ZLabel.String = 'Z (\mum)';
            tmp_max_proj_xy = max(vis_local_image, [], 3);
            tmp_max_proj_yz = squeeze(max(vis_local_image, [], 1));
            tmp_max_proj_xz = squeeze( max(vis_local_image, [], 2));
            vis_local_image(:,:,end) = tmp_max_proj_xy;
            vis_local_image(:,end,:) = tmp_max_proj_xz;
            vis_local_image(1,:,:) = tmp_max_proj_yz;
%             ax_1.XDir = 'reverse';
            ax_1.YDir = 'reverse';
            ax_1.ZDir = 'reverse';
            slice(ax_1, single(vis_local_image), link_global_bbox(5), 1, link_global_bbox(6))
%             slice(ax_1, single(vis_local_image), 1, 1, 1)
            ax_1.DataAspectRatio = [1,1,1];
            ax_1.FontSize = 14;
            ax_1.FontWeight = 'bold';
            
            ax_2 = subplot(1,5,3);
            imagesc(ax_2, tmp_max_proj_xy);
            hold(ax_2, 'on');
            plot(ax_2, link_local_sub(:,2), link_local_sub(:,1), '-r', 'LineWidth', 3)
            scatter(ax_2, vis_skl_sub(:,2), vis_skl_sub(:,1), 'k', 'filled');
            ax_2.XLabel.String = 'X (\mum)';
            ax_2.YLabel.String = 'Y (\mum)';
            ax_2.FontSize = 14;
            ax_2.FontWeight = 'bold';
            daspect([1,1,1]);
            
            ax_3 = subplot(1,5,4);
            imagesc(ax_3, tmp_max_proj_yz.');
            hold(ax_3, 'on');
            plot(ax_3, link_local_sub(:,2), link_local_sub(:,3), '-r', 'LineWidth', 3)
            scatter(ax_3, vis_skl_sub(:,2), vis_skl_sub(:,3), 'k', 'filled');
            ax_3.XLabel.String = 'X (\mum)';
            ax_3.YLabel.String = 'Z (\mum)';
            ax_3.FontSize = 14;
            ax_3.FontWeight = 'bold';
            daspect([1,1,1]);
            
            
            ax_4 = subplot(1,5,5);
            imagesc(ax_4, tmp_max_proj_xz.');
            hold(ax_4, 'on');
            plot(ax_4, link_local_sub(:,1), link_local_sub(:,3), '-r', 'LineWidth', 3)
            scatter(ax_4, vis_skl_sub(:,1), vis_skl_sub(:,3), 'k', 'filled');
            ax_4.XLabel.String = 'Y (\mum)';
            ax_4.YLabel.String = 'Z (\mum)';
            ax_4.FontSize = 14;
            ax_4.FontWeight = 'bold';
            daspect([1,1,1]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image, mask, skel, link_cc_ind, output_name)
            if nargin < 6
                output_name = 'App_label_link';
            end
            app.vessel_image = image;
            app.vessel_graph = graph;
            app.vessel_mask = mask;
            app.output_name = output_name;
            app.link_bbox_expansion_length = 10;
            app.vessel_skl = skel;
            app.link_cc_ind = link_cc_ind;
            app.num_test_link = numel(link_cc_ind);
            app.finishQ = false;
            app.result = struct;
            if app.num_test_link > 0
                [app.removal_Q, app.not_sure_Q, app.artefact_Q, app.artefact_but_keep_Q, ...
                    app.not_sure_but_keep_Q] = deal(false(app.num_test_link, 1));
                app.test_label_idx = 0;
            else
                fprintf('No link need to be checked');
            end
            app.LinkindexEditField.Value = sprintf('%d/%d',app.test_label_idx, app.num_test_link);
            app.fig_hdl = figure('Name','3D visualization');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            if app.test_label_idx < app.num_test_link
                app.test_label_idx = app.test_label_idx + 1;
                app.LinkindexEditField.Value = sprintf('%d/%d',app.test_label_idx, app.num_test_link);
                if app.removal_Q(app.test_label_idx)
                    app.LabelEditField.Value = sprintf('Removed');
                elseif app.not_sure_Q(app.test_label_idx)
                    app.LabelEditField.Value = sprintf('Not sure');
                elseif app.artefact_Q(app.test_label_idx)
                    app.LabelEditField.Value = sprintf('Artefact');
                else
                    app.LabelEditField.Value = sprintf('Kept');
                end
                fun_visualization(app);
            else
                fprintf('Reach the end of the list\n');
            end
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.removal_Q(app.test_label_idx) = true;
            [app.not_sure_but_keep_Q(app.test_label_idx),...
                app.not_sure_Q(app.test_label_idx), ...
                app.artefact_but_keep_Q(app.test_label_idx), ...
                app.artefact_Q(app.test_label_idx)] = deal(false);
            
            app.LabelEditField.Value = sprintf('Removed');
            app.NextButtonPushed()
            
        end

        % Button pushed function: RecoverButton
        function RecoverButtonPushed(app, event)
            [app.removal_Q(app.test_label_idx), ...
                app.not_sure_but_keep_Q(app.test_label_idx),...
                app.not_sure_Q(app.test_label_idx), ...
                app.artefact_but_keep_Q(app.test_label_idx), ...
                app.artefact_Q(app.test_label_idx)] = deal(false);
            app.LabelEditField.Value = sprintf('Kept');
        end

        % Button pushed function: LastButton
        function LastButtonPushed(app, event)
            if app.test_label_idx > 1
                app.test_label_idx = app.test_label_idx - 1;
                app.LinkindexEditField.Value = sprintf('%d/%d',app.test_label_idx, app.num_test_link);
                if app.removal_Q(app.test_label_idx)
                    app.LabelEditField.Value = sprintf('Removed');
                elseif app.not_sure_but_keep_Q(app.test_label_idx)
                    app.LabelEditField.Value = sprintf('Not sure but keep');
                elseif app.artefact_but_keep_Q(app.test_label_idx)
                    app.LabelEditField.Value = sprintf('Artefact but keep');
                elseif app.not_sure_Q(app.test_label_idx)
                    app.LabelEditField.Value = sprintf('Not sure');
                elseif app.artefact_Q(app.test_label_idx)
                    app.LabelEditField.Value = sprintf('Artefact');
                else
                    app.LabelEditField.Value = sprintf('Kept');
                end
                fun_visualization(app);
            else
                fprintf('Reach the begining of the list\n');
            end
        end

        % Button pushed function: SaveresultButton
        function SaveresultButtonPushed(app, event)
            app.result.to_removeQ = app.removal_Q;
            app.result.not_sureQ = app.not_sure_Q;
            app.result.artefactQ = app.artefact_Q;
            app.result.artefact_but_keepQ = app.artefact_but_keep_Q;
            app.result.not_sure_but_keepQ = app.not_sure_but_keep_Q;
            app.finishQ = true;
            assignin('caller', app.output_name, app.result);
            disp('Save result to the current environment');
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
            app.link_bbox_expansion_length = app.link_bbox_expansion_length + 10;
            fun_visualization(app);
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            app.link_bbox_expansion_length = app.link_bbox_expansion_length - 10;
            fun_visualization(app);
        end

        % Button pushed function: NotsureButton
        function NotsureButtonPushed(app, event)
            app.not_sure_Q(app.test_label_idx) = true;
            [app.removal_Q(app.test_label_idx), ...
                app.not_sure_but_keep_Q(app.test_label_idx),...
                app.artefact_but_keep_Q(app.test_label_idx), ...
                app.artefact_Q(app.test_label_idx)] = deal(false);

            app.LabelEditField.Value = sprintf('Not sure');
            app.NextButtonPushed()
        end

        % Button pushed function: ImplayButton
        function ImplayButtonPushed(app, event)
            if isempty(app.link_bbox_expansion_length)
                app.link_bbox_expansion_length = 10;
            end
            image_size = size(app.vessel_image);
            check_link_idx = app.test_label_idx;
            link_ind = app.link_cc_ind{check_link_idx};
            link_global_sub = zeros(numel(link_ind), 3);
            [link_global_sub(:,1), link_global_sub(:,2), link_global_sub(:,3)] = ind2sub(image_size, link_ind);
            link_global_bbox = zeros(1,6);
            link_global_bbox(1:3) = max(1, min(link_global_sub, [], 1) - app.link_bbox_expansion_length);
            link_global_bbox_max = min(image_size, max(link_global_sub, [], 1) + app.link_bbox_expansion_length) ;
            link_global_bbox(4:6) = link_global_bbox_max - link_global_bbox(1:3) + 1;
            vis_local_image = crop_bbox3(app.vessel_image, link_global_bbox, 'default');
            implay(vis_local_image);
        end

        % Button pushed function: ArtefactButton
        function ArtefactButtonPushed(app, event)
            app.artefact_Q(app.test_label_idx) = true;
            [app.removal_Q(app.test_label_idx), ...
                app.not_sure_but_keep_Q(app.test_label_idx),...
                app.not_sure_Q(app.test_label_idx), ...
                app.artefact_but_keep_Q(app.test_label_idx)] = deal(false);
            app.LabelEditField.Value = sprintf('Artefact');
            app.NextButtonPushed()
        end

        % Button pushed function: NotsurebutkeepButton
        function NotsurebutkeepButtonPushed(app, event)
            app.not_sure_but_keep_Q(app.test_label_idx) = true;
            app.not_sure_Q(app.test_label_idx) = true;
            [app.removal_Q(app.test_label_idx), ...
                app.artefact_Q(app.test_label_idx), ...
                app.artefact_but_keep_Q(app.test_label_idx)] = deal(false);
            app.LabelEditField.Value = sprintf('Not sure but keep');
            app.NextButtonPushed()
        end

        % Button pushed function: ArtefactbutkeepButton
        function ArtefactbutkeepButtonPushed(app, event)
            app.artefact_but_keep_Q(app.test_label_idx) = true;
            app.artefact_Q(app.test_label_idx) = true;
            [app.removal_Q(app.test_label_idx), ...
                app.not_sure_but_keep_Q(app.test_label_idx),...
                app.not_sure_Q(app.test_label_idx)] = deal(false);
            app.LabelEditField.Value = sprintf('Artefact but keep');
            app.NextButtonPushed()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.LastButton = uibutton(app.UIFigure, 'push');
            app.LastButton.ButtonPushedFcn = createCallbackFcn(app, @LastButtonPushed, true);
            app.LastButton.Text = 'Last';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.RecoverButton = uibutton(app.UIFigure, 'push');
            app.RecoverButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverButtonPushed, true);
            app.RecoverButton.Text = 'Recover';
            app.SaveresultButton = uibutton(app.UIFigure, 'push');
            app.SaveresultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultButtonPushed, true);
            app.SaveresultButton.Text = 'Save result';
            app.LinkindexEditFieldLabel = uilabel(app.UIFigure);
            app.LinkindexEditFieldLabel.Text = 'Link index';
            app.LinkindexEditField = uieditfield(app.UIFigure, 'text');
            app.Labelinglinks = uilabel(app.UIFigure);
            app.Labelinglinks.Text = 'Labeling links';
            app.ZoomoutButton = uibutton(app.UIFigure, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.ZoominButton = uibutton(app.UIFigure, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.LabelEditFieldLabel = uilabel(app.UIFigure);
            app.LabelEditField = uieditfield(app.UIFigure, 'text');
            app.NotsureButton = uibutton(app.UIFigure, 'push');
            app.NotsureButton.ButtonPushedFcn = createCallbackFcn(app, @NotsureButtonPushed, true);
            app.NotsureButton.Text = 'Not sure';
            app.ImplayButton = uibutton(app.UIFigure, 'push');
            app.ImplayButton.ButtonPushedFcn = createCallbackFcn(app, @ImplayButtonPushed, true);
            app.ImplayButton.Text = 'Implay';
            app.ArtefactButton = uibutton(app.UIFigure, 'push');
            app.ArtefactButton.ButtonPushedFcn = createCallbackFcn(app, @ArtefactButtonPushed, true);
            app.ArtefactButton.Text = 'Artefact';
            app.NotsurebutkeepButton = uibutton(app.UIFigure, 'push');
            app.NotsurebutkeepButton.ButtonPushedFcn = createCallbackFcn(app, @NotsurebutkeepButtonPushed, true);
            app.NotsurebutkeepButton.Text = 'Not sure but keep';
            app.ArtefactbutkeepButton = uibutton(app.UIFigure, 'push');
            app.ArtefactbutkeepButton.ButtonPushedFcn = createCallbackFcn(app, @ArtefactbutkeepButtonPushed, true);
            app.ArtefactbutkeepButton.Text = 'Artefact but keep';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_label_link(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end